classdef hesap_makinesi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HesapMaknesUIFigure     matlab.ui.Figure
        seven                   matlab.ui.control.Button
        eight                   matlab.ui.control.Button
        nine                    matlab.ui.control.Button
        add                     matlab.ui.control.Button
        four                    matlab.ui.control.Button
        five                    matlab.ui.control.Button
        six                     matlab.ui.control.Button
        minus                   matlab.ui.control.Button
        one                     matlab.ui.control.Button
        two                     matlab.ui.control.Button
        three                   matlab.ui.control.Button
        multiply                matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        divided                 matlab.ui.control.Button
        zero                    matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        allrigthsreservedLabel  matlab.ui.control.Label
        HaticeKubraOnayLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: one
        function oneButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: two
        function twoButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: three
        function threeButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: four
        function fourButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: five
        function fiveButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: six
        function sixButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: seven
        function sevenButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: eight
        function eightButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
        end

        % Button pushed function: nine
        function nineButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
            
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text...
                num2str(eval(app.TextArea.Value{1}))];
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: multiply
        function multiplyButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: divided
        function dividedButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: zero
        function zeroButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HesapMaknesUIFigure = uifigure('Visible', 'off');
            app.HesapMaknesUIFigure.Name = 'Hesap Makınesı';
            app.seven = uibutton(app.HesapMaknesUIFigure, 'push');
            app.seven.ButtonPushedFcn = createCallbackFcn(app, @sevenButtonPushed, true);
            app.seven.Text = '7';
            app.eight = uibutton(app.HesapMaknesUIFigure, 'push');
            app.eight.ButtonPushedFcn = createCallbackFcn(app, @eightButtonPushed, true);
            app.eight.Text = '8';
            app.nine = uibutton(app.HesapMaknesUIFigure, 'push');
            app.nine.ButtonPushedFcn = createCallbackFcn(app, @nineButtonPushed, true);
            app.nine.Text = '9';
            app.add = uibutton(app.HesapMaknesUIFigure, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.four = uibutton(app.HesapMaknesUIFigure, 'push');
            app.four.ButtonPushedFcn = createCallbackFcn(app, @fourButtonPushed, true);
            app.four.Text = '4';
            app.five = uibutton(app.HesapMaknesUIFigure, 'push');
            app.five.ButtonPushedFcn = createCallbackFcn(app, @fiveButtonPushed, true);
            app.five.Text = '5';
            app.six = uibutton(app.HesapMaknesUIFigure, 'push');
            app.six.ButtonPushedFcn = createCallbackFcn(app, @sixButtonPushed, true);
            app.six.Text = '6';
            app.minus = uibutton(app.HesapMaknesUIFigure, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '-';
            app.one = uibutton(app.HesapMaknesUIFigure, 'push');
            app.one.ButtonPushedFcn = createCallbackFcn(app, @oneButtonPushed, true);
            app.one.Text = '1';
            app.two = uibutton(app.HesapMaknesUIFigure, 'push');
            app.two.ButtonPushedFcn = createCallbackFcn(app, @twoButtonPushed, true);
            app.two.Text = '2';
            app.three = uibutton(app.HesapMaknesUIFigure, 'push');
            app.three.ButtonPushedFcn = createCallbackFcn(app, @threeButtonPushed, true);
            app.three.Text = '3';
            app.multiply = uibutton(app.HesapMaknesUIFigure, 'push');
            app.multiply.ButtonPushedFcn = createCallbackFcn(app, @multiplyButtonPushed, true);
            app.multiply.Text = '*';
            app.ClearButton = uibutton(app.HesapMaknesUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.divided = uibutton(app.HesapMaknesUIFigure, 'push');
            app.divided.ButtonPushedFcn = createCallbackFcn(app, @dividedButtonPushed, true);
            app.divided.Text = '/';
            app.zero = uibutton(app.HesapMaknesUIFigure, 'push');
            app.zero.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zero.Text = '0';
            app.Button = uibutton(app.HesapMaknesUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '=';
            app.TextArea = uitextarea(app.HesapMaknesUIFigure);
            app.TextArea.Value = {'0'};
            app.allrigthsreservedLabel = uilabel(app.HesapMaknesUIFigure);
            app.allrigthsreservedLabel.Text = '@2021 all rigths reserved';
            app.HaticeKubraOnayLabel = uilabel(app.HesapMaknesUIFigure);
            app.HaticeKubraOnayLabel.Text = 'Hatice Kubra Onay';
            % Show the figure after all components are created
            app.HesapMaknesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hesap_makinesi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HesapMaknesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HesapMaknesUIFigure)
        end
    end
end